classdef imagemedfilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AppByIsnainiDjahaLabel  matlab.ui.control.Label
        ImageProcessingwithMedianFilterLabel  matlab.ui.control.Label
        ResizeButton            matlab.ui.control.Button
        filterButton            matlab.ui.control.Button
        AddnoiseButton          matlab.ui.control.Button
        LoadImageButton         matlab.ui.control.Button
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a,'Parent',app.UIAxes);
        end

        % Button pushed function: AddnoiseButton
        function AddnoiseButtonPushed(app, event)
            global a;
            global J;
            J=a;
            J =imnoise(J,'salt & pepper', 0.4);
            imshow(J,'Parent',app.UIAxes2);
        end

        % Button pushed function: filterButton
        function filterButtonPushed(app, event)
            global J; 
            p=medfilt3(J,[5,5,3]);
            imshow(p,'Parent',app.UIAxes3);
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            global a;
         
            q =imresize(a,0.5,'nearest');
            imshow(q,'Parent',app.UIAxes4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Noise image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Image after remove noise')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Resize')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes4.ZTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.AddnoiseButton = uibutton(app.UIFigure, 'push');
            app.AddnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddnoiseButtonPushed, true);
            app.AddnoiseButton.Text = 'Add noise';
            app.filterButton = uibutton(app.UIFigure, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Text = 'filter ';
            app.ResizeButton = uibutton(app.UIFigure, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.ImageProcessingwithMedianFilterLabel = uilabel(app.UIFigure);
            app.ImageProcessingwithMedianFilterLabel.Text = 'Image Processing with Median Filter';
            app.AppByIsnainiDjahaLabel = uilabel(app.UIFigure);
            app.AppByIsnainiDjahaLabel.Text = 'App By Isnaini Djaha';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imagemedfilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end